classdef Aimag_QC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        Lamp                         matlab.ui.control.Lamp
        Lamptext                     matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        QualityControlButton         matlab.ui.control.Button
        MagnificationLabel           matlab.ui.control.Label
        MagnificationDropDown        matlab.ui.control.DropDown
        FileFormatLabel              matlab.ui.control.Label
        FileFormatDropDown           matlab.ui.control.DropDown
        ImageTypeButtonGroup         matlab.ui.container.ButtonGroup
        BrightFieldButton            matlab.ui.control.RadioButton
        MultiplexButton              matlab.ui.control.RadioButton
        SortSaveImageTilesCheckBox   matlab.ui.control.CheckBox
        SaveHighResolutionMasksCheckBox  matlab.ui.control.CheckBox
        TrainingDataGenCheckBox      matlab.ui.control.CheckBox
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        ImagesListTab                matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        ListBox                      matlab.ui.control.ListBox
        clearButton                  matlab.ui.control.Button
        LogTab                       matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        TextArea                     matlab.ui.control.TextArea
        clearButton_2                matlab.ui.control.Button
        NimagesLabel                 matlab.ui.control.Label
        ConfigTab                    matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        BrightFieldHEorIHCPanel      matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        FocusEditFieldLabel          matlab.ui.control.Label
        FocusEditField               matlab.ui.control.NumericEditField
        ArtefactsEditFieldLabel      matlab.ui.control.Label
        ArtefactsEditField           matlab.ui.control.NumericEditField
        SaturationEditFieldLabel     matlab.ui.control.Label
        SaturationEditField          matlab.ui.control.NumericEditField
        ContrastEditFieldLabel       matlab.ui.control.Label
        ContrastEditField            matlab.ui.control.NumericEditField
        UniformityEditFieldLabel     matlab.ui.control.Label
        UniformityEditField          matlab.ui.control.NumericEditField
        OverallEditFieldLabel        matlab.ui.control.Label
        OverallEditField             matlab.ui.control.NumericEditField
        ImmunoFluorescencePanel      matlab.ui.container.Panel
        GridLayout8_2                matlab.ui.container.GridLayout
        FocusEditField_2Label        matlab.ui.control.Label
        FocusEditField_2             matlab.ui.control.NumericEditField
        ArtefactsEditField_2Label    matlab.ui.control.Label
        ArtefactsEditField_2         matlab.ui.control.NumericEditField
        SaturationEditField_2Label   matlab.ui.control.Label
        SaturationEditField_2        matlab.ui.control.NumericEditField
        ContrastEditField_2Label     matlab.ui.control.Label
        ContrastEditField_2          matlab.ui.control.NumericEditField
        UniformityEditField_2Label   matlab.ui.control.Label
        UniformityEditField_2        matlab.ui.control.NumericEditField
        OverallEditField_2Label      matlab.ui.control.Label
        OverallEditField_2           matlab.ui.control.NumericEditField
        TCTPanel                     matlab.ui.container.Panel
        GridLayout8_3                matlab.ui.container.GridLayout
        FocusEditField_3Label        matlab.ui.control.Label
        FocusEditField_3             matlab.ui.control.NumericEditField
        ArtefactsEditField_3Label    matlab.ui.control.Label
        ArtefactsEditField_3         matlab.ui.control.NumericEditField
        SaturationEditField_3Label   matlab.ui.control.Label
        SaturationEditField_3        matlab.ui.control.NumericEditField
        ContrastEditField_3Label     matlab.ui.control.Label
        ContrastEditField_3          matlab.ui.control.NumericEditField
        UniformityEditField_3Label   matlab.ui.control.Label
        UniformityEditField_3        matlab.ui.control.NumericEditField
        OverallEditField_3Label      matlab.ui.control.Label
        OverallEditField_3           matlab.ui.control.NumericEditField
        CheckBox                     matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        AxePanel                     matlab.ui.container.Panel
        QualitythresholdlevelsLabel  matlab.ui.control.Label
        resetUIButton                matlab.ui.control.Button
        CreditsTab                   matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image5                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        QualityAssessmentofDigitalHistologyImagesVersion40Apr2020Label  matlab.ui.control.Label
        Image6                       matlab.ui.control.Image
        Image7                       matlab.ui.control.Image
        Image8                       matlab.ui.control.Image
        EditField                    matlab.ui.control.EditField
        Button                       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Source_Path
        FileFormat
        Input_Path
        Last_Path = ''
        License = ''
        License_Path = ''
        TimeStamp_Path = ''
        textmod = 0
        ConfigFocus
        ConfigArtefacts
        ConfigSaturation
        ConfigContrast
        ConfigUniformity
        ConfigOverallQuality
        ax =[]
        p1
        p2
        Quality_Thresholds
        Language = 'English'  ;
        spl = '' ;
    end
    
    methods (Access = private)
        
        function PMoving(~, source)
            % Always keep the position rounded to 1 decimal place
            source.Position(1) = round(source.Position(1));
            source.Position(2) = 0 ;
            P1 = find(contains([{source.Parent.Children(:).Tag}],'P1'));
            P2 = find(contains([{source.Parent.Children(:).Tag}],'P2'));
            switch source.Tag
                case 'P1'
                    if source.Position(1)>= round(source.Parent.Children(P2).Position(1))
                        source.Position(1)= round(source.Parent.Children(P2).Position(1))-1 ;
                    end
                    app.Quality_Thresholds = [source.Position(1), round(source.Parent.Children(P2).Position(1)) ] ; %,round(source.Parent.Children(P3).Position(1))] ;
                    source.Label = num2str(app.Quality_Thresholds(1)) ;
                case 'P2'
                    if source.Position(1)<= round(source.Parent.Children(P1).Position(1))
                        source.Position(1)= round(source.Parent.Children(P1).Position(1))+1 ;
                    end
                    app.Quality_Thresholds = [round(source.Parent.Children(P1).Position(1)),source.Position(1) ] ; %, round(source.Parent.Children(P3).Position(1))] ;
                    source.Label = num2str(app.Quality_Thresholds(2)) ;
            end
            % Add any other action here triggered by the movement of the sliders
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Author: Laurent Gole, A!maginostic™. All Rights Reserved, 2020.
            
            
            % DEFINE DEFAULT MAIN FIGURE
            app.UIFigure.Visible    = 'off'             ;
            app.UIFigure.Name       = 'Aimag QC'         ;
            CodePath = which(mfilename)                 ;
            CodePath = split(CodePath,filesep)          ;
            CodePath = join(CodePath(1:end-1),filesep)  ;
            app.Source_Path          = CodePath{1}      ;
            
            
            addpath(genpath(app.Source_Path))
            %ADD useful TAGS :__________________________
            app.UIFigure.Tag        = 'MAINWINDOW'  ;
            %Edit Uifig Top left Icon________________________________
            InsertAppIcon(app.UIFigure,'Aimag_QC','DIH_QC_Logo.ico')    ;
            
            % ADD ALL ICONS HERE :
            app.QualityControlButton.Icon = 'grid.png';
            app.QualityControlButton.IconAlignment = 'left';
            app.clearButton.Icon = '16.png';
            app.clearButton_2.Icon = '16.png';
            app.LoadButton.Icon = 'Open_24.png';
            app.Button.Icon = 'Open_24.png';
            app.LoadButton.Enable = 'off' ;
            app.QualityControlButton.Enable = 'off' ;
            %
            %
            %             % restrict TO KFBIO ONLY! :
            %   app.FileFormatDropDown.Items = app.FileFormatDropDown.Items([1]) ;
            app.TrainingDataGenCheckBox.Value = 0 ;
            %             app.TrainingDataGenCheckBox.Visible = 'off';
            app.SortSaveImageTilesCheckBox.Value = 0;
            %             app.SortSaveImageTilesCheckBox.Visible = 'off';
            app.SaveHighResolutionMasksCheckBox.Value = 0;
            %             app.SaveHighResolutionMasksCheckBox.Visible = 'off';


            %
            %
            drawnow    ;
            %             app.UIFigure.Visible    = 'on' ;
            
            logtext = ['________________________________________________________________________________'] ;
            app.TextArea.Value =logtext;
            logtext =  ['Quality Assessment of Digital Histology Images session: ' char(datetime)] ;
            app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
            app.Image.ImageSource = 'Picture6.png' ;
            app.Image.ScaleMethod = 'fit' ;
            
            app.Image2.ImageSource = 'email_icon.png' ;
            app.Image3.ImageSource = 'googlemaps_icon.png' ;
            app.Image4.ImageSource = 'linkedin_icon.png' ;
            app.Image5.ImageSource = 'web_icon.png' ;
            app.Image6.ImageSource = 'england.png' ;
            app.Image7.ImageSource = 'china.png' ;
            app.Image8.ImageSource = 'Help.png' ;
            
            
            LampSwitch(app,'on','Checking License... ')
            % check the License time remaining
            
            % check the license: if valalid caan use App , else open a
            % licxense menu:::
%             tic
            [app,msgboxText,status] =LICENSECHECKHICHECK2(app) ;

            switch status
                case -1
                    app.UIFigure.Visible    = 'on' ;
                    LampSwitch(app,'on','License Error! ')
                    sel = uiconfirm(app.UIFigure,{msgboxText},...
                        'License Error','Icon','error',...
                        'Options',{'Ok','Exit'}...
                        ,'DefaultOption',1,'CancelOption',1) ;
                    switch sel
                        case 'Ok'
                            app = LicenseMenu_Wrap(app) ;
                        case 'Exit'
                            closereq
                            return
                    end
                    
                case 1
%                    status = 1 
                    LampSwitch(app,'off','Welcome to Aimag QC.')
                case 0
                    app.UIFigure.Visible    = 'on' ;
                    app = LicenseMenu_Wrap(app) ;
                    % check agaain after selection in menu if status is
                    % good now
                   [app,msgboxText,status] =LICENSECHECKHICHECK2(app) ;
           
            end


            if status==1
                
                app.LoadButton.Enable = 'on' ;
                app.QualityControlButton.Enable = 'on' ;
                
                app.TextArea.Value =[join(msgboxText,newline), app.TextArea.Value' ] ;
                
                % Add the line ROI
                app.p1 = images.roi.Point(app.ax,'Position',[15,0],'Tag','P1','Label','P1',"LabelVisible","hover",'Color','k','InteractionsAllowed','none');
                app.p2 = images.roi.Point(app.ax,'Position',[30,0],'Tag','P2','Label','P2',"LabelVisible","hover",'Color','k','InteractionsAllowed','none');
                
                 app.ImageTypeButtonGroup.Children(1).Tag = 'Multiplex' ;
                 app.ImageTypeButtonGroup.Children(2).Tag = 'BrightField' ;


                % reload possible previous path used
                try load([app.Source_Path filesep 'QCV2pathSettings.mat'],'T_*') ;
                    app.Last_Path = T_Path;
                catch
                    app.Last_Path = '';
                end
                app = reload_UI (app) ;
                
                switch app.Language
                    case 'English'
                        app.Image6Clicked() ;
                    case 'Chinese'
                        app.Image7Clicked() ;
                end
                app.UIFigure.Visible    = 'on' ;
                drawnow    ;
                
            else
                closereq
                return
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {430, 430};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            LampSwitch(app,'on','Loading Images... ')
            
            LoadImages_PathQC(app)
            app.Last_Path = app.Input_Path ;
            T_Path = app.Last_Path; %#ok
            save([app.Source_Path filesep 'QCV2pathSettings.mat'], "T_Path",'-append')
            LampSwitch(app,'off','')
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if exist(app.EditField.Value,'dir')==7
                explorer(app.EditField.Value)
            end
        end

        % Button pushed function: QualityControlButton
        function QualityControlButtonPushed(app, event)
            
            
            app.TextArea.Value =['_________________________________________________________________________'] ;
            
            % SAVE UI CONFIG WHEN QC PRESSED:
            T_Cbox_HE = app.CheckBox.Value; %#ok
            T_Cbox_TCT = app.CheckBox_3.Value ;
            T_HE_Focus = app.FocusEditField.Value ;
            T_MF_Focus = app.FocusEditField_2.Value ;
            T_TC_Focus = app.FocusEditField_3.Value ;
            
            T_HE_Artefacts = app.ArtefactsEditField.Value ;
            T_MF_Artefacts = app.ArtefactsEditField_2.Value ;
            T_TC_Artefacts = app.ArtefactsEditField_3.Value ;
            
            T_HE_Saturation = app.SaturationEditField.Value ;
            T_MF_Saturation = app.SaturationEditField_2.Value ;
            T_TC_Saturation = app.SaturationEditField_3.Value ;
            
            T_HE_Contrast = app.ContrastEditField.Value ;
            T_MF_Contrast = app.ContrastEditField_2.Value ;
            T_TC_Contrast = app.ContrastEditField_3.Value ;
            
            T_HE_Uniformity = app.UniformityEditField.Value ;
            T_MF_Uniformity = app.UniformityEditField_2.Value ;
            T_TC_Uniformity = app.UniformityEditField_3.Value ;
            
            T_HE_Overall = app.OverallEditField.Value ;
            T_MF_Overall = app.OverallEditField_2.Value ;
            T_TC_Overall = app.OverallEditField_3.Value ;
            
            T_AX_p1 = app.p1.Position;
            T_AX_p2 = app.p2.Position;
            
            T_Lang = app.Language ;
            
            
            save([app.Source_Path filesep 'QCV2pathSettings.mat'], 'T_*','-append')
            
            % ADD File Config as well


%             
%             try  [TimeStampValue,msgboxText] = READ_TimeStamp_Aimag(app.Source_Path) ;
%             catch
%                 beep
%                 msgboxText = ('License file issue. Please contact Aimaginostic Pte. Ltd. at info@aimaginostic.com.');
%                 LampSwitch(app,'on',msgboxText)
%                 beep
%                 uialert(app.UIFigure, (msgboxText),'Invalid License','Icon','error','CloseFcn',@(src,~)delete(src));
%                 
%             end
%             
%             % check License  time allowed here again according to preloaded license file : (cant keep the app open forever to bypass the License control)
%             [~, isErrorMsg,msgboxText] = License_Check_Aimag_offline(app.License,TimeStampValue,'Aimag QC');
%             if isErrorMsg
%                 LampSwitch(app,'on',msgboxText)
%                 beep
%                 uialert(app.UIFigure, (msgboxText),'Invalid License','Icon','error','CloseFcn',@(src,~)delete(src));
%             else
%                 LampSwitch(app,'off','Welcome to Aimag QC.')
%             end
%             
%             


             [~,~,status] =LICENSECHECKHICHECK2(app) ;

            if status==1
%                 continue
            elseif status<=0
                closereq
                return
            end
            
            
            Sel = outputfolderconfirm(app) ;
            if Sel==0
                return
            end


            LampSwitch(app,'on','Initializing parallel cluster')
            drawnow
            % start a parallell cluster on a predefined number of cores: 90% of available cores
            if isempty(gcp('nocreate'))
                % speed up start ?:
                %                 parpool(1); delete(gcp);
                
                c = parcluster('local'); % build the 'local' cluster object
                nw = c.NumWorkers ;       % get the number of workers
                n_Cores = max(1,floor(0.9*nw));
                parpool(n_Cores) ;
            end
            
            
            LampSwitch(app,'on',' ')


            QCTimer_Start = tic ;


            % first check if List is empty
            if isempty(app.ListBox.Items)
                logtext = ['Images List is empty! Please add Images first.'] ;
                app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
                return
            end
            LampSwitch(app,'on','Starting Quality Control... ')


            %             logtext = ['________________________________________________________________________________'] ;
            %             app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
            %
            logtext = ['Starting Quality Control Session of ' Dir_Extract(app.Input_Path,Dir_Num(app.Input_Path)) ' at ' char(datetime)] ;
            app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;


            ListOfImages = app.ListBox.Items' ;
            %   ImageMagnificationFactor = (app.MagnificationDropDown.Value) ;
            DownsizeCoef = Path_QC_Magtodownsize(app);


            LampSwitch(app,'on','Quality Control in progress...');
            N = numel(ListOfImages) ;
            FileTYPE = app.FileFormatDropDown.Items{contains(app.FileFormatDropDown.ItemsData,app.FileFormatDropDown.Value)} ;
            
            
            userdefinedType = app.ImageTypeButtonGroup.SelectedObject ;
            userdefinedMag  = app.MagnificationDropDown.Value ;
            
            app.TabGroup.SelectedTab = app.LogTab ;
            
            
            for i =  1:N
                %                 if exist('ImageRead','var')
                %                     ImageRead.unloadLib()
                %                 end
                tloop_Start = tic;
                
                % update log and UI text labels
                logtext = (['Quality Control image ' num2str(i) '/' num2str(N) ' in progress...' ]) ;
                app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
                app.ListBox.Value =  app.ListBox.Items(i) ;
                app.NimagesLabel.Text = [num2str(i) '/' num2str(N) ' images'] ;
                drawnow();
                
                % read the image
                ImageRead = FileFormat_Reader(ListOfImages{i},FileTYPE) ;
                
                
                %Double check ImageType
                if contains(lower(ImageRead.MetaData.ImageMode{1}),'brightfield')
                    disp('Image is a Brightfield image')
                    app.ImageTypeButtonGroup.SelectedObject = app.BrightFieldButton ;
                elseif contains(lower(ImageRead.MetaData.ImageMode{1}),'fluorescence')
                    disp('Image is a Fluorescence image')
                    app.ImageTypeButtonGroup.SelectedObject = app.MultiplexButton ;
                else
                    disp('couldnt read image Type info from File using user define type')
                    app.ImageTypeButtonGroup.SelectedObject = userdefinedType ;
                end
                
                %Double check magnification.
                if contains(ImageRead.MetaData.Magnification{1},'40')
                    disp('Image is at 40X magnification')
                    app.MagnificationDropDown.Value = '40X';
                elseif contains(ImageRead.MetaData.Magnification{1},'20')
                    disp('Image is at 20X magnification')
                    app.MagnificationDropDown.Value = '20X';
                elseif contains(ImageRead.MetaData.Magnification{1},'10')
                    disp('Image is at 10X magnification')
                    app.MagnificationDropDown.Value = '10X' ;
                elseif contains(ImageRead.MetaData.Magnification{1},'4')
                    disp('Image is at 4X magnification')
                    app.MagnificationDropDown.Value = '4X' ;
                else
                    disp('Image magnification undetected, using default magnification')
                    app.MagnificationDropDown.Value = userdefinedMag ;
                end
                
                DownsizeCoef = Path_QC_Magtodownsize(app.MagnificationDropDown.Value) ;
                
                logtext = ([ImageRead.MetaData.SlideID{1} ' is a ' app.ImageTypeButtonGroup.SelectedObject.Text ' Image at ' app.MagnificationDropDown.Value ' magnification.']) ;
                app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
                
                
                Outfold = app.EditField.Value ;


                % select which Config
                
                % MAIN CODE FOR THE Quality Control:
                switch app.ImageTypeButtonGroup.SelectedObject.Tag
                    case 'BrightField'
                        if app.CheckBox.Value
                            app.ConfigFocus = app.FocusEditField.Value ;
                            app.ConfigArtefacts = app.ArtefactsEditField.Value ;
                            app.ConfigSaturation = app.SaturationEditField.Value ;
                            app.ConfigContrast = app.ContrastEditField.Value ;
                            app.ConfigUniformity = app.UniformityEditField.Value ;
                            app.ConfigOverallQuality = app.OverallEditField.Value ;
                        elseif app.CheckBox_3.Value
                            app.ConfigFocus = app.FocusEditField_3.Value ;
                            app.ConfigArtefacts = app.ArtefactsEditField_3.Value ;
                            app.ConfigSaturation = app.SaturationEditField_3.Value ;
                            app.ConfigContrast = app.ContrastEditField_3.Value ;
                            app.ConfigUniformity = app.UniformityEditField_3.Value ;
                            app.ConfigOverallQuality = app.OverallEditField_3.Value ;
                        end
                    case 'Multiplex'
                        app.ConfigFocus = app.FocusEditField_2.Value ;
                        app.ConfigArtefacts = app.ArtefactsEditField_2.Value ;
                        app.ConfigSaturation = app.SaturationEditField_2.Value ;
                        app.ConfigContrast = app.ContrastEditField_2.Value ;
                        app.ConfigUniformity = app.UniformityEditField_2.Value ;
                        app.ConfigOverallQuality = app.OverallEditField_2.Value ;
                    case 'TCT'
                end


                % MAIN CODE FOR THE Quality Control:
                switch app.ImageTypeButtonGroup.SelectedObject.Tag
                    case 'BrightField'
                        % DownsizeCoef
                        % [DAPI_d,MASK,Stats] = PathQC_IHC(ImageRead,DownsizeCoef,app) ;
                        [Stats,Decision]= PathQC_IHC(ImageRead,DownsizeCoef,app) ;
                    case 'Multiplex'
                        [~,~,Stats,Decision]= PathQC_Multiplex(ImageRead,DownsizeCoef,app) ;
                end
                % this is for KFBIO ONLY
                %                  try
                %                      ImageRead.unloadLib()
                %                  catch
                %
                %                  end
                
                % this part of script is for nuclei segmentation
                %                 tic
                %                 % temp tif file necessary for block proc in parallell
                %                 tmppath = strrep(ImageRead.Filename,'.qptiff',['_tmp' num2str(1) '.tif']) ;
                %                 ImageRead.createTif(1) ;
                %                 toc
                %
                %                 Hrmask = imresize(logical(QC_MASK==max(QC_MASK(:))),ImageRead.ImSize(1:2)) ;
                %
                %                 % define outputpath for Tiles
                %                 SlideID = ImageRead.MetaData.SlideID{1};
                %                 PageIndex = 1 ;
                %                 ImageType = app.ImageTypeButtonGroup.SelectedObject.Text ;
                %
                %
                %                 % tic
                %                 % this part for Nuclei Segmentation : put in a separatecode
                %                 Outfoldn = [app.EditField.Value  filesep SlideID filesep 'Nuclei_measure'] ;
                %                 mkdir(Outfoldn)
                %                 % % TILE ANALYSIS MAIN CODE :
                %                 % fun = @(block_struct) QC_patchnucleiDC_mask_2(ImageRead.Filename,block_struct,PageIndex,Outfoldn,SlideID,Hrmask,ImageType) ;
                %                 % blockproc(ImageRead.Tmp_path,[256 256],fun,...
                %                 %     'PadPartialBlocks',true,'BorderSize',[0 0],...
                %                 %     'DisplayWaitbar',false,'UseParallel',true...
                %                 %     ) ;
                %                 %
                %                 %       toc
                %
                tloop = toc(tloop_Start) ;
                arrayVal = (table2array(Stats(2,2:6)));
                Dispidx = find(arrayVal==max(arrayVal));
                DispVal = round(100*max(arrayVal))./100 ;
                DispText = Stats.Properties.VariableNames{Dispidx+1};
                DispLoopTime = round(100*tloop/60)./100  ;
                logtext = (['Image is ' Decision ' with ' num2str(DispVal) '% of ' num2str(round(Stats.Total(1))) ' Tiles with ' DispText '. proc time: ' num2str(DispLoopTime) ' min.' ]) ;
                app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;


                % inserting summary saving inside the loop so i can still
                % have it in case it crashes during analysis:
                FOLDNAME = Dir_Extract(app.Input_Path,Dir_Num(app.Input_Path)) ;
                %                 try
                %                     WriteExcelSummary(Outfold,FOLDNAME)
                %                 catch
                
                %                 try
                %                 IMAGEROOTFOLDER = Dir_Extract(ImageRead.Filename,Dir_Num(ImageRead.Filename)-2) ;
                %                 catch
                %                   IMAGEROOTFOLDER = ''
                %                 end
                
                try
                    WriteCsvSummary(Outfold,FOLDNAME)
                    %                 end
                catch
                    disp('Wth')
                end


            end
            
            TStop = toc(QCTimer_Start) ;
            logtext = (['Quality Control done in ' num2str(round(10*TStop/60)/10) ' minutes.' ]) ;
            app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
            logtext = (['__________________________________________________________' ]) ;
            app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;


            if ~exist('Outfold','var')
                disp('all failed')
                return
            end
            
            
            FOLDNAME = Dir_Extract(app.Input_Path,Dir_Num(app.Input_Path)) ;
            % think about saving log file as well as global EXCEL table
            % writing text file log of process.
            writecell(app.TextArea.Value(end:-1:1),[Outfold filesep FOLDNAME '_Log' '.txt'])
            
            
            %                    % readin Table files %
            %            try
            %                WriteExcelSummary(Outfold,FOLDNAME)
            %            catch
            WriteCsvSummary(Outfold,FOLDNAME)
            %            end


            LampSwitch(app,'off','')
            
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            if ~isempty(app.ListBox.Value)
                IDXtoremove = 0 ;
                Listofimagestoremove = app.ListBox.Value ;
                
                for i = 1:numel(app.ListBox.Items)
                    for j = 1:numel(Listofimagestoremove)
                        A= strcmp(app.ListBox.Items{i},Listofimagestoremove{j}) ;
                        if A==1
                            IDXtoremove(j) = i ;
                        end
                    end
                end
                
                app.ListBox.Items(IDXtoremove) = [] ;
                
                NewN = numel( app.ListBox.Items);
                app.NimagesLabel.Text = [num2str(NewN) ' images'] ;
                logtext = (['removing ' num2str(numel(IDXtoremove)) ' images from List.']);
                app.TextArea.Value =[join(logtext,newline), app.TextArea.Value' ] ;
                
            else
                app.ListBox.Items{1} = '-' ;
            end
            
        end

        % Button pushed function: clearButton_2
        function clearButton_2Pushed(app, event)
            app.TextArea.Value = '' ;
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            if app.textmod==0
                if strcmp(event.Key,'l')
                    %  app.LoadButtonPushed
                    
                elseif strcmp(event.Key,'q')
                    % app.QualityControlButtonPushed


                    % CTRL ALT U ALLOWS to UPDATE THE LICENSE FILE
                elseif size(event.Modifier,2)==2 && sum(strcmp(event.Modifier,{'control'  'alt'}))==2 && strcmp(key,'u')
                  % open the license file menu
                  app = LicenseMenu_Wrap(app) ;
                    
                    
                    % updating LicenseFile...
                    
%                     
%                     
%                     [f,p]=  uigetfile({'.aimagl'} ,'Update License File',[app.Last_Path , '/'] );
%                     [L] = dir([app.Source_Path filesep '*.aimagl']) ;
%                     for i = 1:numel(L)
%                         delete([app.Source_Path filesep L.name])
%                     end
%                     try
%                         movefile( [p filesep f] , [app.Source_Path filesep f]);
%                         [~,msgboxText] = READ_LICENSE_Aimag(app.Source_Path) ;
%                         
%                         uialert(app.UIFigure, 'Aimag QC License updated succesfully. please restart the application.','License Update','CloseFcn',@(src,~)delete(src),'Icon','success')
%                         return
%                     catch
%                         beep
%                         uialert(app.UIFigure, 'Error Updating License.','License issue','CloseFcn',@(src,~)delete(src))
%                         return
%                     end
                    
                    
                    % CTRL ALT M ALLOWS to MODIFY THE PARAMER IN CONFIG PANEL
                elseif size(event.Modifier,2)==2 && sum(strcmp(event.Modifier,{'control'  'alt'}))==2 && strcmp(key,'m')
                    app.FocusEditField.Editable =  true  ;
                    app.ArtefactsEditField.Editable = true ;
                    app.SaturationEditField.Editable = true ;
                    app.ContrastEditField.Editable = true ;
                    app.UniformityEditField.Editable = true ;
                    app.OverallEditField.Editable = true ;
                    app.FocusEditField_2.Editable =  true  ;
                    app.ArtefactsEditField_2.Editable = true ;
                    app.SaturationEditField_2.Editable = true ;
                    app.ContrastEditField_2.Editable = true ;
                    app.UniformityEditField_2.Editable = true ;
                    app.OverallEditField_2.Editable = true ;
                    app.FocusEditField_3.Editable =  true  ;
                    app.ArtefactsEditField_3.Editable = true ;
                    app.SaturationEditField_3.Editable = true ;
                    app.ContrastEditField_3.Editable = true ;
                    app.UniformityEditField_3.Editable = true ;
                    app.OverallEditField_3.Editable = true ;
              
                    delete(app.Quality_Thresholds)
                    app.p1.InteractionsAllowed = 'all' ;
                    app.p2.InteractionsAllowed = 'all' ;
                    app.p3.InteractionsAllowed = 'all' ;
                    
                    app.resetUIButton.Visible = 'on' ;
                    
                    addlistener(app.p1,'MovingROI',@(varargin)PMoving(app,app.p1));
                    addlistener(app.p2,'MovingROI',@(varargin)PMoving(app,app.p2));
                  
                else
                    return
                end
                
            else
                app.textmod = 0 ;
                return 
            end


        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            web('https://www.google.com/maps/place/Institute+of+Molecular+and+Cell+Biology/@1.3030314,103.7908458,17z/data=!3m1!4b1!4m5!3m4!1s0x31da1a46ae509429:0x3272272dc683e7cc!8m2!3d1.3030314!4d103.7930398','-browser') ;
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            web('www.aimaginostic.com','-browser') ;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            web('mailto:info@aimaginostic.com','-browser') ;
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            web('https://www.linkedin.com/company/a-maginostic/?viewAsMember=true','-browser')
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
         %   disp('good')
            app.textmod = 1  ;
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
           value = app.EditField.Value;
          %  disp('good')
            app.textmod = 1  ;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                app.CheckBox_3.Value = 0 ;
            else
                app.CheckBox_3.Value = 1 ;
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value
                app.CheckBox.Value = 0 ;
            else
                app.CheckBox.Value = 1 ;
            end
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
          
            app = CN_2_EN(app) ;
            app.Language = 'English';
            T_Lang = app.Language ;
            if exist([app.Source_Path filesep 'QCV2pathSettings.mat'],'file')
                save([app.Source_Path filesep 'QCV2pathSettings.mat'], 'T_Lang','-append')
            else
                save([app.Source_Path filesep 'QCV2pathSettings.mat'], 'T_Lang')
            end
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
%             disp('Translate UI to Chinese')
            app = En_2_CN(app) ;
            app.Language = 'Chinese' ;
            T_Lang = app.Language ;
            if exist([app.Source_Path filesep 'QCV2pathSettings.mat'],'file')
                save([app.Source_Path filesep 'QCV2pathSettings.mat'], 'T_Lang','-append')
            else
                save([app.Source_Path filesep 'QCV2pathSettings.mat'], 'T_Lang')
            end
        end

        % Button pushed function: resetUIButton
        function resetUIButtonPushed(app, event)
            if exist([app.Source_Path filesep 'QCV2pathSettings.mat'],'file')
                delete([app.Source_Path filesep 'QCV2pathSettings.mat'])
                delete(app.UIFigure)
                return
            end
            
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            switch app.Language
                case 'English'
                    winopen([app.Source_Path filesep 'Icons' filesep 'Aimag_QC_UserGuide_EN.pdf'])
                case 'Chinese'
                    winopen([app.Source_Path filesep 'Icons' filesep 'Aimag_QC_UserGuide_CN.pdf'])
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            
            switch selectedTab
                
                case app.ConfigTab
                    
                    if isempty(app.ax)
                    % Create the 1D axes
                    app.ax = axes( ...
                        app.AxePanel,'Color','none','YColor','none','XLim',[0,100],'YTick',[], ...
                        'XTick',0:10:100,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
                        'on','Units','pixels','Position', [10,20,525,0] ...
                        );
                    app.ax.Title.String = 'QC Thresholds:' ;
                    app.ax.Title.Position = [1 11 1] ;
                    app.ax.Title.HorizontalAlignment = 'left' ;
                    app.ax.Title.FontWeight = "normal" ;
                    app.ax.Title.VerticalAlignment = 'baseline' ;
                    app.ax.Color = 'none' ;
                    app.ax.YColor = 'none' ;
                    app.Quality_Thresholds =    plot( app.ax, [15,30],[1,1],'ok') ;
                    app.ax.XLim = [0, 100] ;
                    % app.ax.YLim = [0, 1] ;
                    app.ax.YTick = []  ;
                    app.ax.TickDir = 'both';
                    app.ax.TickLength = [0.01,0.015] ;
                    app.ax.XMinorTick = 'on' ;
                    app.ax.Units = 'pixels' ;
                    app.ax.Toolbar.Visible = 'off' ;
                    %   app.ax.YAxis.Direction = 'reverse' ;
                    % Disable the interactivity & toolbar visibility
                    disableDefaultInteractivity(app.ax);
                    app.ax.Toolbar.Visible = 'off';
                    end
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.Lamp = uilamp(app.GridLayout6);
            app.Lamptext = uilabel(app.GridLayout6);
            app.Lamptext.Text = 'Status';
            app.LoadButton = uibutton(app.GridLayout6, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load ';
            app.QualityControlButton = uibutton(app.GridLayout6, 'push');
            app.QualityControlButton.ButtonPushedFcn = createCallbackFcn(app, @QualityControlButtonPushed, true);
            app.QualityControlButton.Enable = 'off';
            app.QualityControlButton.Text = 'Quality Control';
            app.MagnificationLabel = uilabel(app.GridLayout6);
            app.MagnificationLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.GridLayout6);
            app.MagnificationDropDown.Items = {'4X', '10X', '20X', '40X'};
            app.MagnificationDropDown.Value = '20X';
            app.FileFormatLabel = uilabel(app.GridLayout6);
            app.FileFormatLabel.Text = 'FileFormat:';
            app.FileFormatDropDown = uidropdown(app.GridLayout6);
            app.FileFormatDropDown.Items = {'KFBIO', ''};
            app.FileFormatDropDown.ItemsData = {'.kfb'};
            app.FileFormatDropDown.Value = '.kfb';
            app.ImageTypeButtonGroup = uibuttongroup(app.GridLayout6);
            app.ImageTypeButtonGroup.Title = 'Image Type:';
            app.BrightFieldButton = uiradiobutton(app.ImageTypeButtonGroup);
            app.BrightFieldButton.Text = 'BrightField';
            app.BrightFieldButton.Value = true;
            app.MultiplexButton = uiradiobutton(app.ImageTypeButtonGroup);
            app.MultiplexButton.Enable = 'off';
            app.MultiplexButton.Text = 'Multiplex';
            app.SortSaveImageTilesCheckBox = uicheckbox(app.GridLayout6);
            app.SortSaveImageTilesCheckBox.Enable = 'off';
            app.SortSaveImageTilesCheckBox.Visible = 'off';
            app.SortSaveImageTilesCheckBox.Text = '   Sort & Save Image Tiles';
            app.SaveHighResolutionMasksCheckBox = uicheckbox(app.GridLayout6);
            app.SaveHighResolutionMasksCheckBox.Enable = 'off';
            app.SaveHighResolutionMasksCheckBox.Visible = 'off';
            app.SaveHighResolutionMasksCheckBox.Text = '   Save High Resolution Masks';
            app.TrainingDataGenCheckBox = uicheckbox(app.GridLayout6);
            app.TrainingDataGenCheckBox.Enable = 'off';
            app.TrainingDataGenCheckBox.Visible = 'off';
            app.TrainingDataGenCheckBox.Text = '  Training Data Gen';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImagesListTab = uitab(app.TabGroup);
            app.ImagesListTab.Title = 'Images List';
            app.GridLayout3 = uigridlayout(app.ImagesListTab);
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.clearButton = uibutton(app.GridLayout3, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.GridLayout4 = uigridlayout(app.LogTab);
            app.TextArea = uitextarea(app.GridLayout4);
            app.clearButton_2 = uibutton(app.GridLayout4, 'push');
            app.clearButton_2.ButtonPushedFcn = createCallbackFcn(app, @clearButton_2Pushed, true);
            app.clearButton_2.Text = 'clear';
            app.NimagesLabel = uilabel(app.GridLayout4);
            app.NimagesLabel.Text = '';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.GridLayout7 = uigridlayout(app.ConfigTab);
            app.BrightFieldHEorIHCPanel = uipanel(app.GridLayout7);
            app.BrightFieldHEorIHCPanel.Title = 'BrightField (H&E or IHC)';
            app.GridLayout8 = uigridlayout(app.BrightFieldHEorIHCPanel);
            app.FocusEditFieldLabel = uilabel(app.GridLayout8);
            app.FocusEditFieldLabel.Text = 'Focus';
            app.FocusEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FocusEditField.Editable = 'off';
            app.FocusEditField.Value = 1600;
            app.ArtefactsEditFieldLabel = uilabel(app.GridLayout8);
            app.ArtefactsEditFieldLabel.Text = 'Artefacts';
            app.ArtefactsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ArtefactsEditField.Editable = 'off';
            app.ArtefactsEditField.Value = 0.01;
            app.SaturationEditFieldLabel = uilabel(app.GridLayout8);
            app.SaturationEditFieldLabel.Text = 'Saturation';
            app.SaturationEditField = uieditfield(app.GridLayout8, 'numeric');
            app.SaturationEditField.Editable = 'off';
            app.SaturationEditField.Value = 90;
            app.ContrastEditFieldLabel = uilabel(app.GridLayout8);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ContrastEditField.Editable = 'off';
            app.ContrastEditField.Value = 70;
            app.UniformityEditFieldLabel = uilabel(app.GridLayout8);
            app.UniformityEditFieldLabel.Text = 'Uniformity';
            app.UniformityEditField = uieditfield(app.GridLayout8, 'numeric');
            app.UniformityEditField.Editable = 'off';
            app.UniformityEditField.Value = 900;
            app.OverallEditFieldLabel = uilabel(app.GridLayout8);
            app.OverallEditFieldLabel.Text = 'Overall ';
            app.OverallEditField = uieditfield(app.GridLayout8, 'numeric');
            app.OverallEditField.Editable = 'off';
            app.OverallEditField.Value = 2;
            app.ImmunoFluorescencePanel = uipanel(app.GridLayout7);
            app.ImmunoFluorescencePanel.Title = 'ImmunoFluorescence';
            app.GridLayout8_2 = uigridlayout(app.ImmunoFluorescencePanel);
            app.FocusEditField_2Label = uilabel(app.GridLayout8_2);
            app.FocusEditField_2Label.Enable = 'off';
            app.FocusEditField_2Label.Text = 'Focus';
            app.FocusEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.FocusEditField_2.Editable = 'off';
            app.FocusEditField_2.Enable = 'off';
            app.FocusEditField_2.Value = 50;
            app.ArtefactsEditField_2Label = uilabel(app.GridLayout8_2);
            app.ArtefactsEditField_2Label.Enable = 'off';
            app.ArtefactsEditField_2Label.Text = 'Artefacts';
            app.ArtefactsEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.ArtefactsEditField_2.Editable = 'off';
            app.ArtefactsEditField_2.Enable = 'off';
            app.ArtefactsEditField_2.Value = 0.025;
            app.SaturationEditField_2Label = uilabel(app.GridLayout8_2);
            app.SaturationEditField_2Label.Enable = 'off';
            app.SaturationEditField_2Label.Text = 'Saturation';
            app.SaturationEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.SaturationEditField_2.Editable = 'off';
            app.SaturationEditField_2.Enable = 'off';
            app.SaturationEditField_2.Value = 90;
            app.ContrastEditField_2Label = uilabel(app.GridLayout8_2);
            app.ContrastEditField_2Label.Enable = 'off';
            app.ContrastEditField_2Label.Text = 'Contrast';
            app.ContrastEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.ContrastEditField_2.Editable = 'off';
            app.ContrastEditField_2.Enable = 'off';
            app.ContrastEditField_2.Value = 30;
            app.UniformityEditField_2Label = uilabel(app.GridLayout8_2);
            app.UniformityEditField_2Label.Enable = 'off';
            app.UniformityEditField_2Label.Text = 'Uniformity';
            app.UniformityEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.UniformityEditField_2.Editable = 'off';
            app.UniformityEditField_2.Enable = 'off';
            app.UniformityEditField_2.Value = 300;
            app.OverallEditField_2Label = uilabel(app.GridLayout8_2);
            app.OverallEditField_2Label.Enable = 'off';
            app.OverallEditField_2Label.Text = 'Overall ';
            app.OverallEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.OverallEditField_2.Editable = 'off';
            app.OverallEditField_2.Enable = 'off';
            app.OverallEditField_2.Value = 2;
            app.TCTPanel = uipanel(app.GridLayout7);
            app.TCTPanel.Title = 'TCT';
            app.GridLayout8_3 = uigridlayout(app.TCTPanel);
            app.FocusEditField_3Label = uilabel(app.GridLayout8_3);
            app.FocusEditField_3Label.Text = 'Focus';
            app.FocusEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.FocusEditField_3.Editable = 'off';
            app.FocusEditField_3.Value = 800;
            app.ArtefactsEditField_3Label = uilabel(app.GridLayout8_3);
            app.ArtefactsEditField_3Label.Text = 'Artefacts';
            app.ArtefactsEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.ArtefactsEditField_3.Editable = 'off';
            app.ArtefactsEditField_3.Value = 0.0077;
            app.SaturationEditField_3Label = uilabel(app.GridLayout8_3);
            app.SaturationEditField_3Label.Text = 'Saturation';
            app.SaturationEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.SaturationEditField_3.Editable = 'off';
            app.SaturationEditField_3.Value = 90;
            app.ContrastEditField_3Label = uilabel(app.GridLayout8_3);
            app.ContrastEditField_3Label.Text = 'Contrast';
            app.ContrastEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.ContrastEditField_3.Editable = 'off';
            app.ContrastEditField_3.Value = 70;
            app.UniformityEditField_3Label = uilabel(app.GridLayout8_3);
            app.UniformityEditField_3Label.Text = 'Uniformity';
            app.UniformityEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.UniformityEditField_3.Editable = 'off';
            app.UniformityEditField_3.Value = 900;
            app.OverallEditField_3Label = uilabel(app.GridLayout8_3);
            app.OverallEditField_3Label.Text = 'Overall ';
            app.OverallEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.OverallEditField_3.Editable = 'off';
            app.OverallEditField_3.Value = 2;
            app.CheckBox = uicheckbox(app.GridLayout7);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.GridLayout7);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.GridLayout7);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.AxePanel = uipanel(app.GridLayout7);
            app.QualitythresholdlevelsLabel = uilabel(app.AxePanel);
            app.QualitythresholdlevelsLabel.Text = 'Quality threshold levels:';
            app.resetUIButton = uibutton(app.GridLayout7, 'push');
            app.resetUIButton.ButtonPushedFcn = createCallbackFcn(app, @resetUIButtonPushed, true);
            app.resetUIButton.Visible = 'off';
            app.resetUIButton.Text = 'reset UI';
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.GridLayout2 = uigridlayout(app.CreditsTab);
            app.Image = uiimage(app.GridLayout2);
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image5 = uiimage(app.GridLayout2);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.QualityAssessmentofDigitalHistologyImagesVersion40Apr2020Label = uilabel(app.GridLayout2);
            app.QualityAssessmentofDigitalHistologyImagesVersion40Apr2020Label.Text = 'Quality Assessment of Digital Histology Images. Version 4.3 Oct. 2020. Laurent Gole.';
            app.Image6 = uiimage(app.GridLayout2);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image7 = uiimage(app.GridLayout2);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image8 = uiimage(app.GridLayout2);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.Tooltip = {'open   user  guide'};
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aimag_QC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end